classdef senaleslabtwo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ConvolucinButton             matlab.ui.control.Button
        dominio                      matlab.ui.control.DropDown
        SeleccioneeldominioenelquedeseatrabajarDropDownLabel  matlab.ui.control.Label
        labelexpfinal2               matlab.ui.control.Label
        labelexpinicio2              matlab.ui.control.Label
        expfinal2                    matlab.ui.control.NumericEditField
        expinicio2                   matlab.ui.control.NumericEditField
        labelexpfinal1               matlab.ui.control.Label
        labelexpinicio1              matlab.ui.control.Label
        expfinal1                    matlab.ui.control.NumericEditField
        expinicio1                   matlab.ui.control.NumericEditField
        Label4                       matlab.ui.control.Label
        Label3                       matlab.ui.control.Label
        Listafuncionxt_2             matlab.ui.control.DropDown
        SealhtLabel                  matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        Label1                       matlab.ui.control.Label
        Listafuncionxt               matlab.ui.control.DropDown
        SealxtDropDownLabel          matlab.ui.control.Label
        Figura2EditField2            matlab.ui.control.NumericEditField
        AceptarButton_2              matlab.ui.control.Button
        AceptarButton                matlab.ui.control.Button
        Figura2EditField1            matlab.ui.control.NumericEditField
        Figura1EditField2            matlab.ui.control.NumericEditField
        Figura1EditField1            matlab.ui.control.NumericEditField
        Laboratorio2ConvolucinLabel  matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            value = app.Listafuncionxt.Value;
            value2=app.dominio.Value;
            if strcmp(value2,"Dominio continuo")
                 if strcmp(value,"Señal senoidal")
                   
                  w = app.Figura1EditField2.Value;
                  a = app.Figura1EditField1.Value;
                  t= 0:1/(20*w):w;
                  y=a*sin(2*pi*(1/w)*t);
                  app.UIAxes.XLim = [0,w];
                  app.UIAxes.YLim = [-a-2,a+2];
                  plot(app.UIAxes,t,y)
                 elseif strcmp(value,"Señal exponencial")
                    a = app.Figura1EditField1.Value;
                    b = app.Figura1EditField2.Value;
                    pin=app.expinicio1.Value;
                    pf=app.expfinal1.Value;
                    t = pin:1/20:pf;
                    l = exp(b*t);
                    y = l.*a;
                    l1=exp(b*pin);
                    l2=exp(b*pf);
                    limyinf=l1.*a;
                    limysup=l2.*a;
                    app.UIAxes.XLim = [pin,pf];
                   if b < 0
                    app.UIAxes.YLim = [limysup,limyinf];
                    plot(app.UIAxes,t,y)
                    else
                    app.UIAxes.YLim = [limyinf,limysup];
                    plot(app.UIAxes,t,y)
                   end
                 elseif strcmp(value,"Señal rectangular")
                     a=app.Figura1EditField1.Value;
                     w=app.Figura1EditField2.Value;
                     t = 0:1/(20*w):w;
                     y = a*square(2*pi*(1/w)*t);
                     app.UIAxes.XLim = [0,w];
                     app.UIAxes.YLim = [-a-2,a+2];
                     plot(app.UIAxes,t,y)
                 elseif strcmp(value,"Señal triangular")
                     w=app.Figura1EditField2.Value;
                     a=app.Figura1EditField1.Value;
                     t = 0:1/(40*w):w;
                     y = a*sawtooth(2*pi*(1/w)*t);
                     app.UIAxes.XLim = [0,w];
                     app.UIAxes.YLim = [-a-2,a+2];
                     plot(app.UIAxes,t,y)
                 elseif strcmp(value,"Señal rampa 1")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(1/3)-(pin1+(lon/3)));
                     y1(t1 <pf1-lon*(2/3))=0;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon/3))*pend;
                     y1(t1>=pf1-(lon/3))=1;
                     app.UIAxes.XLim = [pin1-1,pf1+1];
                     app.UIAxes.YLim = [0,1.5];
                     plot(app.UIAxes,t1,y1)
                 elseif strcmp(value,"Señal rampa 2")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-(pin1+(lon*(2/3))));
                     y1(t1 <=pf1-lon*(2/3))=1;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon*(2/3)))*pend;
                     y1(t1>pf1-(lon/3))=0;
                     app.UIAxes.XLim = [pin1-1,pf1+1];
                     app.UIAxes.YLim = [0,1.5];
                     plot(app.UIAxes,t1,y1)
                 elseif strcmp(value, "Señal rampa 3")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-pin1);
                     y1(t1 <=pf1-lon*(2/3))=(t1(t1<=pf1-lon*(2/3))-pin1)*pend;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=1;
                     y1(t1>=pf1-(lon/3))=(t1(t1>=pf1-lon*(1/3))-pf1)*(-pend);
                     app.UIAxes.XLim = [pin1-1,pf1+1];
                     app.UIAxes.YLim = [0,1.5];
                     plot(app.UIAxes,t1,y1)
                 end
            elseif strcmp(value2,"Dominio discreto")
                if strcmp(value,"Señal senoidal")
                   
                  w = app.Figura1EditField2.Value;
                  a = app.Figura1EditField1.Value;
                  t= 0:1/(5*w):w;
                  
                  y=a*sin(2*pi*(1/w)*t);
                  app.UIAxes.XLim = [0,w];
                  app.UIAxes.YLim = [-a-2,a+2];
                  stem(app.UIAxes,t,y)
                   elseif strcmp(value,"Señal exponencial")
                    a = app.Figura1EditField1.Value;
                    b = app.Figura1EditField2.Value;
                    pin=app.expinicio1.Value;
                    pf=app.expfinal1.Value;
                    t = pin:1/5:pf;
                    l = exp(b*t);
                    y = l.*a;
                    l1=exp(b*pin);
                    l2=exp(b*pf);
                    limyinf=l1.*a;
                    limysup=l2.*a;
                    app.UIAxes.XLim = [pin,pf];
                   if b < 0
                    app.UIAxes.YLim = [limysup,limyinf];
                    stem(app.UIAxes,t,y)
                    else
                    app.UIAxes.YLim = [limyinf,limysup];
                    stem(app.UIAxes,t,y)
                   end
                    elseif strcmp(value,"Señal rectangular")
                     a=app.Figura1EditField1.Value;
                     w=app.Figura1EditField2.Value;
                     t = 0:1/(2*w):w;
                     y = a*square(2*pi*(1/w)*t);
                     app.UIAxes.XLim = [0,w];
                     app.UIAxes.YLim = [-a-2,a+2];
                     stem(app.UIAxes,t,y)
                    elseif strcmp(value,"Señal triangular")
                     w=app.Figura1EditField2.Value;
                     a=app.Figura1EditField1.Value;
                     t = 0:1/(2*w):w;
                     y = a*sawtooth(2*pi*(1/w)*t);
                     app.UIAxes.XLim = [0,w];
                     app.UIAxes.YLim = [-a-2,a+2];
                     stem(app.UIAxes,t,y)
                elseif strcmp(value,"Señal rampa 1")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.5:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(1/3)-(pin1+(lon/3)));
                     y1(t1 <pf1-lon*(2/3))=0;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon/3))*pend;
                     y1(t1>=pf1-(lon/3))=1;
                     app.UIAxes.XLim = [pin1-1,pf1+1];
                     app.UIAxes.YLim = [0,1.5];
                     stem(app.UIAxes,t1,y1)
                elseif strcmp(value,"Señal rampa 2")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.5:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-(pin1+(lon*(2/3))));
                     y1(t1 <=pf1-lon*(2/3))=1;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon*(2/3)))*pend;
                     y1(t1>pf1-(lon/3))=0;
                     app.UIAxes.XLim = [pin1-1,pf1+1];
                     app.UIAxes.YLim = [0,1.5];
                     stem(app.UIAxes,t1,y1)
                elseif strcmp(value, "Señal rampa 3")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.5:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-pin1);
                     y1(t1 <=pf1-lon*(2/3))=(t1(t1<=pf1-lon*(2/3))-pin1)*pend;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=1;
                     y1(t1>=pf1-(lon/3))=(t1(t1>=pf1-lon*(1/3))-pf1)*(-pend);
                     app.UIAxes.XLim = [pin1-1,pf1+1];
                     app.UIAxes.YLim = [0,1.5];
                     stem(app.UIAxes,t1,y1)
                end
            end
        end

        % Value changed function: Listafuncionxt
        function ListafuncionxtValueChanged(app, event)
            value = app.Listafuncionxt.Value;
            if strcmp(value,"Señal exponencial")
                app.Label1.Text =" Amplitud(a) ";
                app.Label2.Text="Valor de b";
                app.labelexpinicio1.Visible="on";
                app.labelexpfinal1.Visible="on";
                app.expinicio1.Visible="on";
                app.expfinal1.Visible="on";
            else
                app.labelexpinicio1.Visible="of";
                app.labelexpfinal1.Visible="of";
                app.expinicio1.Visible="of";
                app.expfinal1.Visible="of";
            if strcmp(value,"Señal senoidal")
                app.Label1.Text =" Amplitud ";
                app.Label2.Text=" Periodo ";
            elseif strcmp(value,"Señal rampa 1")
                app.Label1.Text ="Punto de inico";
                app.Label2.Text="Punto final";
            elseif strcmp(value,"Señal rampa 2")
                app.Label1.Text ="Punto de inico";
                app.Label2.Text="Punto final";
            elseif strcmp(value,"Señal rampa 3")
                app.Label1.Text ="Punto de inico";
                app.Label2.Text="Punto final";
            elseif strcmp(value,"Señal triangular")
                app.Label1.Text =" Amplitud ";
                app.Label2.Text="Periodo";
            elseif strcmp(value,"Señal rectangular")
                app.Label1.Text =" Amplitud ";
                app.Label2.Text=" Periodo ";
            end
            end
        end

        % Value changed function: Listafuncionxt_2
        function Listafuncionxt_2ValueChanged(app, event)
            value = app.Listafuncionxt_2.Value;
            if strcmp(value,"Señal exponencial")
                app.Label3.Text =" Amplitud(a) ";
                app.Label4.Text="Valor de b";
                app.labelexpinicio2.Visible="on";
                app.labelexpfinal2.Visible="on";
                app.expinicio2.Visible="on";
                app.expfinal2.Visible="on";
            else
                app.labelexpinicio2.Visible="of";
                app.labelexpfinal2.Visible="of";
                app.expinicio2.Visible="of";
                app.expfinal2.Visible="of";
            if strcmp(value,"Señal senoidal")
                app.Label3.Text =" Amplitud ";
                app.Label4.Text="Periodo";
            elseif strcmp(value,"Señal rampa 1")
                app.Label3.Text ="Punto de inico";
                app.Label4.Text="Punto final";
            elseif strcmp(value,"Señal rampa 2")
                app.Label3.Text ="Punto de inico";
                app.Label4.Text="Punto final";
            elseif strcmp(value,"Señal rampa 3")
                app.Label3.Text ="Punto de inico";
                app.Label4.Text="Punto final";
            elseif strcmp(value,"Señal triangular")
                app.Label3.Text =" Amplitud ";
                app.Label4.Text="Periodo";
            elseif strcmp(value,"Señal rectangular")
                app.Label3.Text =" Amplitud ";
                app.Label4.Text="Periodo";
            end
            end
        end

        % Button pushed function: AceptarButton_2
        function AceptarButton_2Pushed(app, event)
            value = app.Listafuncionxt_2.Value;
            value2=app.dominio.Value;
           if strcmp(value2,"Dominio continuo")
             if strcmp(value,"Señal senoidal")
               
              w = app.Figura2EditField2.Value;
              a = app.Figura2EditField1.Value;
              t= 0:1/(10*w):w;
              
              y=a*sin(2*pi*(1/w)*t);
              app.UIAxes2.XLim = [0,w];
              app.UIAxes2.YLim = [-a-2,a+2];
              plot(app.UIAxes2,t,y)
             elseif strcmp(value,"Señal exponencial")
                a = app.Figura2EditField1.Value;
                b = app.Figura2EditField2.Value;
                pin=app.expinicio2.Value;
                pf=app.expfinal2.Value;
                t = pin:1/20:pf;
                l = exp(b*t);
                y = l.*a;
                l1=exp(b*pin);
                l2=exp(b*pf);
                limyinf=l1.*a;
                limysup=l2.*a;
                app.UIAxes2.XLim = [pin,pf];
                if b < 0
                app.UIAxes2.YLim = [limysup,limyinf];
                plot(app.UIAxes2,t,y)
                else
                app.UIAxes2.YLim = [limyinf,limysup];
                plot(app.UIAxes2,t,y)
                end
             elseif strcmp(value,"Señal rectangular")
                 a=app.Figura2EditField1.Value;
                 w=app.Figura2EditField2.Value;
                 t = 0:1/(20*w):w;
                 y = a*square(2*pi*(1/w)*t);
                 app.UIAxes2.XLim = [0,w];
                 app.UIAxes2.YLim = [-a-2,a+2];
                 plot(app.UIAxes2,t,y)
             elseif strcmp(value,"Señal triangular")
                 w=app.Figura2EditField2.Value;
                 a=app.Figura2EditField1.Value;
                 t = 0:1/(40*w):w;
                 y = a*sawtooth(2*pi*(1/w)*t);
                 app.UIAxes2.XLim = [0,w];
                 app.UIAxes2.YLim = [-a-2,a+2];
                 plot(app.UIAxes2,t,y)
             elseif strcmp(value,"Señal rampa 1")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(1/3)-(pin2+(lon2/3)));
                     y2(t2 <pf2-lon2*(2/3))=0;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2/3))*pend2;
                     y2(t2>=pf2-(lon2/3))=1;
                     app.UIAxes2.XLim = [pin2-1,pf2+1];
                     app.UIAxes2.YLim = [0,1.5];
                     plot(app.UIAxes2,t2,y2)
             elseif strcmp(value,"Señal rampa 2")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-(pin2+(lon2*(2/3))));
                     y2(t2 <=pf2-lon2*(2/3))=1;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2*(2/3)))*pend2;
                     y2(t2>pf2-(lon2/3))=0;
                     app.UIAxes2.XLim = [pin2-1,pf2+1];
                     app.UIAxes2.YLim = [0,1.5];
                     plot(app.UIAxes2,t2,y2)
             elseif strcmp(value, "Señal rampa 3")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-pin2);
                     y2(t2 <=pf2-lon2*(2/3))=(t2(t2<=pf2-lon2*(2/3))-pin2)*pend2;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=1;
                     y2(t2>=pf2-(lon2/3))=(t2(t2>=pf2-lon2*(1/3))-pf2)*(-pend2);
                     app.UIAxes2.XLim = [pin2-1,pf2+1];
                     app.UIAxes2.YLim = [0,1.5];
                     plot(app.UIAxes2,t2,y2)
             end
           elseif strcmp(value2,"Dominio discreto")
               if strcmp(value,"Señal senoidal")
               
              w = app.Figura2EditField2.Value;
              a = app.Figura2EditField1.Value;
              t= 0:1/(5*w):w;
              
              y=a*sin(2*pi*(1/w)*t);
              app.UIAxes2.XLim = [0,w];
              app.UIAxes2.YLim = [-a-2,a+2];
              stem(app.UIAxes2,t,y)
               elseif strcmp(value,"Señal exponencial")
                a = app.Figura2EditField1.Value;
                b = app.Figura2EditField2.Value;
                pin=app.expinicio2.Value;
                pf=app.expfinal2.Value;
                t = pin:1/5:pf;
                l = exp(b*t);
                y = l.*a;
                l1=exp(b*pin);
                l2=exp(b*pf);
                limyinf=l1.*a;
                limysup=l2.*a;
                app.UIAxes2.XLim = [pin,pf];
                if b < 0
                app.UIAxes2.YLim = [limysup,limyinf];
                stem(app.UIAxes2,t,y)
                else
                app.UIAxes2.YLim = [limyinf,limysup];
                stem(app.UIAxes2,t,y)
                end
                elseif strcmp(value,"Señal rectangular")
                 a=app.Figura2EditField1.Value;
                 w=app.Figura2EditField2.Value;
                 t = 0:1/(2*w):w;
                 y = a*square(2*pi*(1/w)*t);
                 app.UIAxes2.XLim = [0,w];
                 app.UIAxes2.YLim = [-a-2,a+2];
                 stem(app.UIAxes2,t,y)
                  elseif strcmp(value,"Señal triangular")
                 w=app.Figura2EditField2.Value;
                 a=app.Figura2EditField1.Value;
                 t = 0:1/(2*w):w;
                 y = a*sawtooth(2*pi*(1/w)*t);
                 app.UIAxes2.XLim = [0,w];
                 app.UIAxes2.YLim = [-a-2,a+2];
                 stem(app.UIAxes2,t,y)
             elseif strcmp(value,"Señal rampa 1")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.5:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(1/3)-(pin2+(lon2/3)));
                     y2(t2 <pf2-lon2*(2/3))=0;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2/3))*pend2;
                     y2(t2>=pf2-(lon2/3))=1;
                     app.UIAxes2.XLim = [pin2-1,pf2+1];
                     app.UIAxes2.YLim = [0,1.5];
                     stem(app.UIAxes2,t2,y2)
             elseif strcmp(value,"Señal rampa 2")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.5:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-(pin2+(lon2*(2/3))));
                     y2(t2 <=pf2-lon2*(2/3))=1;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2*(2/3)))*pend2;
                     y2(t2>pf2-(lon2/3))=0;
                     app.UIAxes2.XLim = [pin2-1,pf2+1];
                     app.UIAxes2.YLim = [0,1.5];
                     stem(app.UIAxes2,t2,y2)
             elseif strcmp(value, "Señal rampa 3")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.5:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-pin2);
                     y2(t2 <=pf2-lon2*(2/3))=(t2(t2<=pf2-lon2*(2/3))-pin2)*pend2;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=1;
                     y2(t2>=pf2-(lon2/3))=(t2(t2>=pf2-lon2*(1/3))-pf2)*(-pend2);
                     app.UIAxes2.XLim = [pin2-1,pf2+1];
                     app.UIAxes2.YLim = [0,1.5];
                     stem(app.UIAxes2,t2,y2)
               end
               
           end
        end

        % Button pushed function: ConvolucinButton
        function ConvolucinButtonPushed(app, event)
          value=app.dominio.Value;
          value2=app.Listafuncionxt.Value;
          value3=app.Listafuncionxt_2.Value;
          if strcmp(value,"Dominio continuo")
              if strcmp(value2,"Señal senoidal")
                  w1 = app.Figura1EditField2.Value;
                  a1 = app.Figura1EditField1.Value;
                  t1= 0:(1/(20*w1)):w1;
                  y1=a1*sin(2*pi*(1/w1)*t1);
              elseif strcmp(value2,"Señal exponencial")
                    a1 = app.Figura1EditField1.Value;
                    b1 = app.Figura1EditField2.Value;
                    pin1=app.expinicio1.Value;
                    pf1=app.expfinal1.Value;
                    t1 = pin1:1/20:pf1;
                    l1 = exp(b1*t1);
                    y1 = l1.*a1;
              elseif strcmp(value2,"Señal rectangular")
                     a1=app.Figura1EditField1.Value;
                     w1=app.Figura1EditField2.Value;
                     t1 = 0:(1/(20*w1)):w1;
                     y1 = a1*square(2*pi*(1/w1)*t1);
              elseif strcmp(value2,"Señal triangular")
                     w1=app.Figura1EditField2.Value;
                     a1=app.Figura1EditField1.Value;
                     t1 = 0:(1/(20*w1)):w1;
                     y1 = a1*sawtooth(2*pi*(1/w1)*t1);
              elseif strcmp(value2,"Señal rampa 1")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(1/3)-(pin1+(lon/3)));
                     y1(t1 <pf1-lon*(2/3))=0;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon/3))*pend;
                     y1(t1>=pf1-(lon/3))=1;
              elseif strcmp(value2,"Señal rampa 2")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-(pin1+(lon*(2/3))));
                     y1(t1 <=pf1-lon*(2/3))=1;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon*(2/3)))*pend;
                     y1(t1>pf1-(lon/3))=0;
                     
              elseif strcmp(value2, "Señal rampa 3")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-pin1);
                     y1(t1 <=pf1-lon*(2/3))=(t1(t1<=pf1-lon*(2/3))-pin1)*pend;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=1;
                     y1(t1>=pf1-(lon/3))=(t1(t1>=pf1-lon*(1/3))-pf1)*(-pend);
              end
                  if strcmp(value3,"Señal senoidal")
                      w2 = app.Figura2EditField2.Value;
                      a2 = app.Figura2EditField1.Value;
                      t2= 0:(1/(20*w2)):w2;
                      y2=a2*sin(2*pi*(1/w2)*t2);
                  elseif strcmp(value3,"Señal exponencial")
                    a2 = app.Figura2EditField1.Value;
                    b2 = app.Figura2EditField2.Value;
                    pin2=app.expinicio2.Value;
                    pf2=app.expfinal2.Value;
                    t2 = pin2:1/20:pf2;
                    l2 = exp(b2*t2);
                    y2 = l2.*a2;
                  elseif strcmp(value3,"Señal rectangular")
                     a2=app.Figura2EditField1.Value;
                     w2=app.Figura2EditField2.Value;
                     t2 = 0:((1/(20*w2))):w2;
                     y2 = a2*square(2*pi*(1/w2)*t2);
                  elseif strcmp(value3,"Señal triangular")
                     w2=app.Figura2EditField2.Value;
                     a2=app.Figura2EditField1.Value;
                     t2 = 0:((1/(20*w2))):w2;
                     y2 = a2*sawtooth(2*pi*(1/w2)*t2);
                  elseif strcmp(value3,"Señal rampa 1")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(1/3)-(pin2+(lon2/3)));
                     y2(t2 <pf2-lon2*(2/3))=0;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2/3))*pend2;
                     y2(t2>=pf2-(lon2/3))=1;
                  elseif strcmp(value3,"Señal rampa 2")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-(pin2+(lon2*(2/3))));
                     y2(t2 <=pf2-lon2*(2/3))=1;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2*(2/3)))*pend2;
                     y2(t2>pf2-(lon2/3))=0;
                  elseif strcmp(value3, "Señal rampa 3")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-pin2);
                     y2(t2 <=pf2-lon2*(2/3))=(t2(t2<=pf2-lon2*(2/3))-pin2)*pend2;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=1;
                     y2(t2>=pf2-(lon2/3))=(t2(t2>=pf2-lon2*(1/3))-pf2)*(-pend2);
                  end
             len1=length(y1);
             len2=length(y2);
             i=1;
             j=1;
             y3=[y1,zeros(1,len2)];
             y4=[y2,zeros(1,len1)];
             for i=1:len1+len2-1
                 convo(i)=0;
                 for j=1:len1
                     if(i-j+1>0)
                         convo(i)=convo(i)+(y3(j)*y4(i-j+1));
                     end
                 end
             end
             lencon=length(convo);
             pin2=y1(1)-len2;
             pfin2=y1(end)+len2;
             p=(pfin2-pin2)/(lencon-1);
             tnew=pin2:p:pfin2;
             lent=length(tnew);
             i=1;
             plot(app.UIAxes3,tnew,convo);
             while i < lent
                 app.UIAxes4.XLim = [tnew(1),tnew(end)];
                 plot(app.UIAxes4,tnew(1:i),convo(1:i));
                 i=i+1;
                 pause(0.0005);
             end
          
      
%%Convolución discreto
          else
               if strcmp(value2,"Señal senoidal")
                  w1 = app.Figura1EditField2.Value;
                  a1 = app.Figura1EditField1.Value;
                  t1= 0:1/(2*w1):w1;
                  y1=a1*sin(2*pi*(1/w1)*t1);
              elseif strcmp(value2,"Señal exponencial")
                    a1 = app.Figura1EditField1.Value;
                    b1 = app.Figura1EditField2.Value;
                    pin1=app.expinicio1.Value;
                    pf1=app.expfinal1.Value;
                    t1 = pin1:1/20:pf1;
                    l1 = exp(b1*t1);
                    y1 = l1.*a1;
              elseif strcmp(value2,"Señal rectangular")
                     a1=app.Figura1EditField1.Value;
                     w1=app.Figura1EditField2.Value;
                     t1 = 0:1/(2*w1):w1;
                     y1 = a1*square(2*pi*(1/w1)*t1);
              elseif strcmp(value2,"Señal triangular")
                     w1=app.Figura1EditField2.Value;
                     a1=app.Figura1EditField1.Value;
                     t1 = 0:1/(2*w1):w1;
                     y1 = a1*sawtooth(2*pi*(1/w1)*t1);
              elseif strcmp(value2,"Señal rampa 1")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(1/3)-(pin1+(lon/3)));
                     y1(t1 <pf1-lon*(2/3))=0;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon/3))*pend;
                     y1(t1>=pf1-(lon/3))=1;
              elseif strcmp(value2,"Señal rampa 2")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-(pin1+(lon*(2/3))));
                     y1(t1 <=pf1-lon*(2/3))=1;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=((t1((t1>pf1-lon*(2/3))&(t1<pf1-lon/3)))-(pin1+lon*(2/3)))*pend;
                     y1(t1>pf1-(lon/3))=0;
              elseif strcmp(value2, "Señal rampa 3")
                     pin1=app.Figura1EditField1.Value;
                     pf1=app.Figura1EditField2.Value;
                     t1 =pin1:0.1:pf1;
                     y1 = t1*0;
                     lon=pf1-pin1;
                     pend=1/(pf1-lon*(2/3)-pin1);
                     y1(t1 <=pf1-lon*(2/3))=(t1(t1<=pf1-lon*(2/3))-pin1)*pend;
                     y1((t1>pf1-lon*(2/3)) & (t1<pf1-(lon/3)))=1;
                     y1(t1>=pf1-(lon/3))=(t1(t1>=pf1-lon*(1/3))-pf1)*(-pend);
                    
              end
                  if strcmp(value3,"Señal senoidal")
                      w2 = app.Figura2EditField2.Value;
                      a2 = app.Figura2EditField1.Value;
                      t2= 0:1/(2*w2):w2;
                      y2=a2*sin(2*pi*(1/w2)*t2);
                  elseif strcmp(value3,"Señal exponencial")
                    a2 = app.Figura2EditField1.Value;
                    b2 = app.Figura2EditField2.Value;
                    pin2=app.expinicio2.Value;
                    pf2=app.expfinal2.Value;
                    t2 = pin2:1/20:pf2;
                    l2 = exp(b2*t2);
                    y2 = l2.*a2;
                  elseif strcmp(value3,"Señal rectangular")
                     a2=app.Figura2EditField1.Value;
                     w2=app.Figura2EditField2.Value;
                     t2 = 0:1/(2*w2):w2;
                     y2 = a2*square(2*pi*(1/w2)*t2);
                  elseif strcmp(value3,"Señal triangular")
                     w2=app.Figura2EditField2.Value;
                     a2=app.Figura2EditField1.Value;
                     t2 = 0:1/(2*w2):w2;
                     y2 = a2*sawtooth(2*pi*(1/w2)*t2);
                  elseif strcmp(value3,"Señal rampa 1")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(1/3)-(pin2+(lon2/3)));
                     y2(t2 <pf2-lon2*(2/3))=0;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2/3))*pend2;
                     y2(t2>=pf2-(lon2/3))=1;
                  elseif strcmp(value3,"Señal rampa 2")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-(pin2+(lon2*(2/3))));
                     y2(t2 <=pf2-lon2*(2/3))=1;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=((t2((t2>pf2-lon2*(2/3))&(t2<pf2-lon2/3)))-(pin2+lon2*(2/3)))*pend2;
                     y2(t2>pf2-(lon2/3))=0;
                  elseif strcmp(value3, "Señal rampa 3")
                     pin2=app.Figura2EditField1.Value;
                     pf2=app.Figura2EditField2.Value;
                     t2 =pin2:0.1:pf2;
                     y2 = t2*0;
                     lon2=pf2-pin2;
                     pend2=1/(pf2-lon2*(2/3)-pin2);
                     y2(t2 <=pf2-lon2*(2/3))=(t2(t2<=pf2-lon2*(2/3))-pin2)*pend2;
                     y2((t2>pf2-lon2*(2/3)) & (t2<pf2-(lon2/3)))=1;
                     y2(t2>=pf2-(lon2/3))=(t2(t2>=pf2-lon2*(1/3))-pf2)*(-pend2);
                  end
             len1=length(y1);
             len2=length(y2);
             i=1;
             j=1;
             y3=[y1,zeros(1,len2)];
             y4=[y2,zeros(1,len1)];
             for i=1:len1+len2-1
                 convo(i)=0;
                 for j=1:len1
                     if(i-j+1>0)
                         convo(i)=convo(i)+(y3(j)*y4(i-j+1));
                     end
                 end
             end
             lencon=length(convo);
             pin2=y1(1)-len2;
             pfin2=y1(end)+len2;
             p=(pfin2-pin2)/(lencon-1);
             tnew=pin2:p:pfin2;
             lent=length(tnew);
             i=1;
             stem(app.UIAxes3,tnew,convo);
             while i < lent
                 app.UIAxes4.XLim = [tnew(1),tnew(end)];
                 stem(app.UIAxes4,tnew(1:i),convo(1:i));
                 i=i+1;
                 pause(0.0005);
             end
          end
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'X(t)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Convolución')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'H(t)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Animación convolución')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Laboratorio2ConvolucinLabel = uilabel(app.UIFigure);
            app.Laboratorio2ConvolucinLabel.Text = 'Laboratorio 2 Convolución';
            app.Figura1EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.Figura1EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.Figura2EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.AceptarButton_2 = uibutton(app.UIFigure, 'push');
            app.AceptarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AceptarButton_2Pushed, true);
            app.AceptarButton_2.Text = 'Aceptar';
            app.Figura2EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.SealxtDropDownLabel = uilabel(app.UIFigure);
            app.SealxtDropDownLabel.Text = 'Señal x(t)';
            app.Listafuncionxt = uidropdown(app.UIFigure);
            app.Listafuncionxt.Items = {'Señal senoidal', 'Señal rampa 1', 'Señal rampa 2', 'Señal exponencial', 'Señal rectangular', 'Señal triangular', 'Señal rampa 3'};
            app.Listafuncionxt.ValueChangedFcn = createCallbackFcn(app, @ListafuncionxtValueChanged, true);
            app.Listafuncionxt.Value = 'Señal senoidal';
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'Amplitud';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Periodo';
            app.SealhtLabel = uilabel(app.UIFigure);
            app.SealhtLabel.Text = 'Señal h(t)';
            app.Listafuncionxt_2 = uidropdown(app.UIFigure);
            app.Listafuncionxt_2.Items = {'Señal senoidal', 'Señal rampa 1', 'Señal rampa 2', 'Señal exponencial', 'Señal rectangular', 'Señal triangular', 'Señal rampa 3'};
            app.Listafuncionxt_2.ValueChangedFcn = createCallbackFcn(app, @Listafuncionxt_2ValueChanged, true);
            app.Listafuncionxt_2.Value = 'Señal senoidal';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Amplitud';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Período';
            app.expinicio1 = uieditfield(app.UIFigure, 'numeric');
            app.expinicio1.Visible = 'off';
            app.expfinal1 = uieditfield(app.UIFigure, 'numeric');
            app.expfinal1.Visible = 'off';
            app.labelexpinicio1 = uilabel(app.UIFigure);
            app.labelexpinicio1.Visible = 'off';
            app.labelexpinicio1.Text = 'Punto inicial';
            app.labelexpfinal1 = uilabel(app.UIFigure);
            app.labelexpfinal1.Visible = 'off';
            app.labelexpfinal1.Text = 'Punto final';
            app.expinicio2 = uieditfield(app.UIFigure, 'numeric');
            app.expinicio2.Visible = 'off';
            app.expfinal2 = uieditfield(app.UIFigure, 'numeric');
            app.expfinal2.Visible = 'off';
            app.labelexpinicio2 = uilabel(app.UIFigure);
            app.labelexpinicio2.Visible = 'off';
            app.labelexpinicio2.Text = 'Punto inicial';
            app.labelexpfinal2 = uilabel(app.UIFigure);
            app.labelexpfinal2.Visible = 'off';
            app.labelexpfinal2.Text = 'Punto final';
            app.SeleccioneeldominioenelquedeseatrabajarDropDownLabel = uilabel(app.UIFigure);
            app.SeleccioneeldominioenelquedeseatrabajarDropDownLabel.Text = 'Seleccione el dominio en el que desea trabajar';
            app.dominio = uidropdown(app.UIFigure);
            app.dominio.Items = {'Dominio continuo', 'Dominio discreto'};
            app.dominio.Value = 'Dominio continuo';
            app.ConvolucinButton = uibutton(app.UIFigure, 'push');
            app.ConvolucinButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolucinButtonPushed, true);
            app.ConvolucinButton.Text = 'Convolución';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = senaleslabtwo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end